classdef BRSoft_ver12 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DataMenu                      matlab.ui.container.Menu
        ImportMenu                    matlab.ui.container.Menu
        SomnomedicsMenu               matlab.ui.container.Menu
        FinapresMenu                  matlab.ui.container.Menu
        TableMenu                     matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        robertmalinowskimedunigreifswalddeMenu  matlab.ui.container.Menu
        BRSPanel                      matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        SequenceBRSTab                matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes3_2                     matlab.ui.control.UIAxes
        xBRSTab                       matlab.ui.container.Tab
        UIAxes3_3                     matlab.ui.control.UIAxes
        spectralBRSTab                matlab.ui.container.Tab
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes4_2                     matlab.ui.control.UIAxes
        UIAxes4_3                     matlab.ui.control.UIAxes
        UIAxes4_4                     matlab.ui.control.UIAxes
        UIAxes4_5                     matlab.ui.control.UIAxes
        UIAxes4_6                     matlab.ui.control.UIAxes
        alphaLFLabel                  matlab.ui.control.Label
        alphaHfLabel                  matlab.ui.control.Label
        transfLFLabel                 matlab.ui.control.Label
        transfHFLabel                 matlab.ui.control.Label
        aLF                           matlab.ui.control.Label
        aHF                           matlab.ui.control.Label
        tLF                           matlab.ui.control.Label
        tHF                           matlab.ui.control.Label
        PlotPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        PropertiesPanel               matlab.ui.container.Panel
        RunButton                     matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        RawTab                        matlab.ui.container.Tab
        InterpolateArtefactsCheckBox  matlab.ui.control.CheckBox
        ArtfctpEditFieldLabel         matlab.ui.control.Label
        ArtfctpEditField              matlab.ui.control.NumericEditField
        SetinterpolationFactorLabel   matlab.ui.control.Label
        SeqTab                        matlab.ui.container.Tab
        lagEditFieldLabel             matlab.ui.control.Label
        lagEditField                  matlab.ui.control.NumericEditField
        SetlaginbeatsbetweenRRandSBPLabel  matlab.ui.control.Label
        SetminBPchangesinmmHgLabel    matlab.ui.control.Label
        deltaBPEditFieldLabel         matlab.ui.control.Label
        deltaBPEditField              matlab.ui.control.NumericEditField
        SetminBPchangesinmsLabel      matlab.ui.control.Label
        deltaRREditFieldLabel         matlab.ui.control.Label
        deltaRREditField              matlab.ui.control.NumericEditField
        SettheminnumberbeatsSequenceLabel  matlab.ui.control.Label
        minSeqLengthLabel             matlab.ui.control.Label
        minSeqLengthEditField         matlab.ui.control.NumericEditField
        XTab                          matlab.ui.container.Tab
        SpecTab                       matlab.ui.container.Tab
    end

    
    properties (Access = private)
        
        % Somno
        isSomno
        STrrfile 
        STrrpath

        STsbpfile
        STsbppath
        
        STrrTime
        
        
        % FinaPres
        isFP
        FPfile
        FPpath
        
        % Table
        isTAP
        TAPfile
        TAPpath
        
        % ImputData
        RR
        SBP
        Time
        
        % Data to Cal
        RRuse
        SBPuse
        Timeuse
        
        % BRS Values
        seqBRS
        specBRS
        xBRS
        
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    
    methods (Access = private)
        function results =  readFina(app)
            % Read RR and BP Data
            disp("Importing Data");
            %fid = fopen(fullfile(app.FPpath,app.FPfile));
            
            opts = delimitedTextImportOptions("NumVariables", 14);

            % Specify range and delimiter
            opts.DataLines = [27, Inf];
            opts.Delimiter = ";";
            
            % Specify column names and types
            opts.VariableNames = ["T", "SBP", "DBP", "MBP", "HR", "SV", "LeftVentricularEjectionTimems", "PulseIntervalms", "MaximumSlopemmHgs", "CardiacOutputlmin", "TotalPeripheralResistanceMedicalUnitmmHgminl", "TotalPeripheralResistanceCGSdynscm5", "Markers", "VarName14"];
            opts.VariableTypes = ["string", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "string", "string"];
            opts = setvaropts(opts, [1, 13, 14], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, [1, 13, 14], "EmptyFieldRule", "auto");
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            
            % Import the data
            data = readtable(fullfile(app.FPpath,app.FPfile), opts);
            app.RR =  (60./data.HR) * 1000;
            app.SBP = data.SBP;
            
            fmt = 'HH:mm:ss.SSS';
            dt = datetime(data.T, 'InputFormat', fmt, 'Format', fmt);
          
            
            % Use RR-Time to check if datapoint start is OK
            app.Time = milliseconds(dt - dt(1)); 


        end


        function results = readSomno(app)
            % Read RR Data
            disp('Import Data, beginning with RR - File!');
            fid = fopen(fullfile(app.STrrpath,app.STrrfile));
            app.RR = textscan(fid, '%s%s', 'Delimiter', ';','TreatAsEmpty','~');
            fclose(fid);
            % Get Header _rr
            rr_SigID = app.RR{1,1}(1);
            rr_StartTime = app.RR{1,1}(2);
            rr_Units = app.RR{1,1}(3);
            rr_SigType = app.RR{1,1}(4);
            
            % Del Header from Data
            app.RR{1,1}(1:4) = [];  % Time
            app.RR{1,2}(1:4) = [];  % RR
            
            fmt = 'HH:mm:ss,SSS';
            dtrr = datetime(app.RR{1,1}, 'InputFormat', fmt, 'Format', fmt);
            app.Time = milliseconds(dtrr - dtrr(1)); 
            app.RR =  app.RR{1,2};
            
            % Read SBP Data
            disp('Import Data, SBP - File!');
            fid = fopen(fullfile(app.STsbppath,app.STsbpfile));
            app.SBP = textscan(fid, '%s%s', 'Delimiter', ';','TreatAsEmpty','~');
            fclose(fid);
            
            % Get Header _sbp
            sbp_SigID = app.SBP{1,1}(1);
            sbp_StartTime = app.SBP{1,1}(2);
            sbp_Units = app.SBP{1,1}(3);
            sbp_SigType = app.SBP{1,1}(4);
            
            % Del Header from Data
            app.SBP{1,1}(1:4) = [];  % Time
            app.SBP{1,2}(1:4) = [];  % RR
            
            fmt = 'HH:mm:ss,SSS';
            dtsbp = datetime(app.SBP{1,1}, 'InputFormat', fmt, 'Format', fmt);
            
            % Use RR-Time to check if datapoint start is OK
            TimeSBP = milliseconds(dtsbp - dtrr(1)); 
            if TimeSBP(1) > app.Time(1) && TimeSBP(1) < app.Time(2)
                disp('RR and SBP are consistent!')
                
            else
                disp('Check Data')
                return;
            end
            
            app.SBP =  app.SBP{1,2};
            sbp = [];
            
            for r=1:length(app.Time)- 1
                isSet = 0;
                for s=1:length(app.SBP)
                    if TimeSBP(s) > app.Time(r) && TimeSBP(s) < app.Time(r+1) && ~isSet
                        if ~isSet
                            sbp = [sbp app.SBP(s)];
                            isSet = true;
                        elseif TimeSBP(s) ~='A'
                            sbp(end) = app.SBP(s);
                        else
                            %...
                        end
                        
                    else
                        % Do nothing!  
                    end
                end
            end
            app.SBP = sbp.';     
  
            % Make the same Length
            lenSBP = length(app.SBP);
            lenRR = length(app.RR);
            dL = lenRR - lenSBP - 1;
            if dL > 0
                app.RR(lenRR-dL:lenRR) = [];
                app.Time(lenRR-dL:lenRR) = [];
            elseif dL < 0
                app.SBP(lenSBP-dL:lenSBP) = [];
                
            end
            
            % 'A' to value
            idx = find(strcmp(app.RR, 'A'));
            app.RR(idx) = cellstr('1500')
            
            idx = find(strcmp(app.SBP, 'A'));
            app.SBP(idx) = cellstr('300');
            
            % Cellstr to double
           
            d = str2double(app.RR);
            app.RR = d;
            d = str2double(app.SBP);
            app.SBP = d;
            clear d;
            
        end
        
        function results = plotValues(app)
            plot(app.UIAxes,app.Time,app.RRuse);
            plot(app.UIAxes2,app.Time,app.SBPuse);
        end
        
        function results = plotBRSseq(app)
            
            brs = app.seqBRS;
            %################# Plot #####
            %############################
            BPup = brs.BPvalUp;
            BPdown = brs.BPvalDown;
            
            
% %             f = figure;
% %             set(gcf, 'Name', 'SeqBRS');
            %set(gcf, 'Position', get(0, 'Screensize'));
            % if v 
            % set(gcf, 'Visible','on');
            % else
            % set(gcf, 'Visible','off');    
            % end
            % ############
            
            
% %             subplot(1,2,1)
%%           hold on
            b = 0;
            b2 = 0;
            N = length(BPup);
            
            xmin = min(BPup(1).X);
            xmax = max(BPup(1).X);
            
            ymin = min(BPup(1).Y);
            ymax = max(BPup(1).Y);
            hold(app.UIAxes3,'on')
            for i=1:N
                scatter(app.UIAxes3,BPup(i).X,BPup(i).Y) 
                plot(app.UIAxes3,BPup(i).X,BPup(i).Fit)
                
                b = b + BPup(i).m(2);
                b2 = b2 + BPup(i).m(1);
                
                xmin = min(xmin,min(BPup(i).X));
                xmax = max(xmax,max(BPup(i).X));
                ymin = min(ymin,min(BPup(i).Y));
                ymax = max(ymax,max(BPup(i).Y));
            end
            x = xmin:1:xmax;
            y = (b/N)*x + (b2/N);
            
            plot(app.UIAxes3,x,y,'--','LineWidth',3);
            axis(app.UIAxes3,[xmin xmax ymin ymax]);
            t = ['Slope(BRS):','\n','BRSup:  ',num2str(brs.BRSup),' (ms/mmHg) \n','BRSupN:  ',num2str(brs.BRSupN)];
            t = sprintf(t);
            text(app.UIAxes3,xmin+1,ymax-15,t);
            grid(app.UIAxes3,'on');
            xlabel(app.UIAxes3,'BloodPressure (mmHg)')
            ylabel(app.UIAxes3,'IBI (ms)')
            title(app.UIAxes3,'BRS - UpSeq.');
            
            % legend('Seq.-Points',t)
            
            % ###########
% %             subplot(1,2,2)
% %             hold on
            b = 0;
            b2 = 0;
            N = length(BPdown);
            
            xmin = min(BPdown(1).X);
            xmax = max(BPdown(1).X);
            ymin = min(BPdown(1).Y);
            ymax = max(BPdown(1).Y);
            
            hold(app.UIAxes3_2,'on');
            for i=1:N
                scatter(app.UIAxes3_2,BPdown(i).X,BPdown(i).Y); 
                plot(app.UIAxes3_2,BPdown(i).X,BPdown(i).Fit);
                
                b = b + BPdown(i).m(2);
                b2 = b2 + BPdown(i).m(1);
                
                xmin = min(xmin,min(BPdown(i).X));
                xmax = max(xmax,max(BPdown(i).X));
                
                ymin = min(ymin,min(BPdown(i).Y));
                ymax = max(ymax,max(BPdown(i).Y));
            end
            x = xmin:1:xmax;
            y = (b/N)*x + (b2/N);
            
            plot(app.UIAxes3_2,x,y,'--','LineWidth',3);
            axis(app.UIAxes3_2,[xmin xmax ymin ymax]);
            t = ['Slope(BRS):','\n','BRSdown:  ',num2str(brs.BRSdown),' (ms/mmHg) \n','BRSdownN:  ',num2str(brs.BRSdownN)];
            t = sprintf(t);
            text(app.UIAxes3_2,xmin+1,ymax-15,t);
            grid(app.UIAxes3_2,'on');
            xlabel(app.UIAxes3_2,'BloodPressure (mmHg)')
            ylabel(app.UIAxes3_2,'IBI (ms)')
            title(app.UIAxes3_2,'BRS - DownSequences ');
            
        end
    
    
        function results = plotXbrs(app)
            BRS = app.xBRS.AllVal;
            if ~app.xBRS.isOK
                t = sprintf('No Values found!');
                text(app.UIAxes3_3,0.5,0.5,t,'FontSize',25);
                
            else
            b = 0;
            b2 = 0;
            N = length(BRS);
            
            xmin = min(BRS(1).X);
            xmax = max(BRS(1).X);
            
            ymin = min(BRS(1).Y);
            ymax = max(BRS(1).Y);
            hold(app.UIAxes3_3,'on')
            for i=1:N
                scatter(app.UIAxes3_3,BRS(i).X,BRS(i).Y);
                plot(app.UIAxes3_3,BRS(i).X,BRS(i).Fit);
                
                b = b + BRS(i).m(2);
                b2 = b2 + BRS(i).m(1);
                
                xmin = min(xmin,min(BRS(i).X));
                xmax = max(xmax,max(BRS(i).X));
                ymin = min(ymin,min(BRS(i).Y));
                ymax = max(ymax,max(BRS(i).Y));
            end
            x = xmin:1:xmax;
            y = (b/N)*x + (b2/N);
            plot(app.UIAxes3_3,x,y,'--','LineWidth',3);  axis(app.UIAxes3_3,[xmin xmax ymin ymax]);
            t = ['Slope(xBRS):  ', num2str(b/N),' (ms/mmHg)'];
            t = sprintf(t);
            text(app.UIAxes3_3,xmin+1,ymax-5,t);
            grid(app.UIAxes3_3,'on');
            xlabel(app.UIAxes3_3,'BloodPressure (mmHg)')
            ylabel(app.UIAxes3_3,'IBI (ms)')
            title(app.UIAxes3_3,'xBRS');
                
            end
            
            
        end
    
    
        function results = plotSpec(app)
            %################# Plot #####
            %############################
            
            %figure
            % ############
            %subplot(3,2,1)
            hold(app.UIAxes4,'on');
            yMax = max(app.specBRS.Pxx([app.specBRS.areaLF;app.specBRS.areaHF]));
            if isempty(yMax)
                 yMax = max(app.specBRS.Pxx(fix(length(app.specBRS.Pxx)/5):length(app.specBRS.Pxx)));
            end
            plot(app.UIAxes4,app.specBRS.F,app.specBRS.Pxx(1:length(app.specBRS.F)),'c','LineWidth',2,...
                                  'MarkerEdgeColor','k',...
                                  'MarkerFaceColor','g',...
                                  'MarkerSize',10); axis(app.UIAxes4,[0 0.5 0 yMax]);
            h=bar(app.UIAxes4,app.specBRS.F(app.specBRS.areaLF),app.specBRS.Pxx(app.specBRS.areaLF));
            h.EdgeColor = 'r';
            h.FaceColor = 'r';
            
            h2=bar(app.UIAxes4,app.specBRS.F(app.specBRS.areaHF),app.specBRS.Pxx(app.specBRS.areaHF));
            h2.EdgeColor = 'b';
            h2.FaceColor = 'b';
            grid(app.UIAxes4,'on');
            xlabel(app.UIAxes4,'Fequency (Hz)')
            title(app.UIAxes4,'Power Spectral Density - SBP');
            legend(app.UIAxes4,'SBP-PSD','LF','HF')
            % ###########
            %subplot(3,2,2)
            hold(app.UIAxes4_2,'on');
            yMax = max(app.specBRS.Pyy([app.specBRS.areaLF;app.specBRS.areaHF]));
            if isempty(yMax)
                 yMax = max(app.specBRS.Pyy(fix(length(app.specBRS.Pyy)/5):length(app.specBRS.Pyy)));
            end
            plot(app.UIAxes4_2,app.specBRS.F,app.specBRS.Pyy(1:length(app.specBRS.F)),'c','LineWidth',2,...
                                  'MarkerEdgeColor','k',...
                                  'MarkerFaceColor','g',...
                                  'MarkerSize',10); axis(app.UIAxes4_2,[0 0.5 0 yMax]);
            h=bar(app.UIAxes4_2,app.specBRS.F(app.specBRS.areaLF),app.specBRS.Pyy(app.specBRS.areaLF));
            h.EdgeColor = 'r';
            h.FaceColor = 'r';
            
            h2=bar(app.UIAxes4_2,app.specBRS.F(app.specBRS.areaHF),app.specBRS.Pyy(app.specBRS.areaHF));
            h2.EdgeColor = 'b';
            h2.FaceColor = 'b';
            grid(app.UIAxes4_2,'on')
            xlabel(app.UIAxes4_2,'Fequency (Hz)')
            title(app.UIAxes4_2,'Power Spectral Density - IBI');
            legend(app.UIAxes4_2,'IBI-PSD','LF','HF')
            % ###########
            %subplot(3,2,3)
            hold(app.UIAxes4_3,'on');
            yMax = max(abs(app.specBRS.Pxy([app.specBRS.areaLF;app.specBRS.areaHF])));
            if isempty(yMax)
                 yMax = max(abs(app.specBRS.Pxy(fix(length(app.specBRS.Pxy)/5):length(app.specBRS.Pxy))));
            end
            plot(app.UIAxes4_3,app.specBRS.F,abs(app.specBRS.Pxy(1:length(app.specBRS.F))),'c','LineWidth',2,...
                                  'MarkerEdgeColor','k',...
                                  'MarkerFaceColor','g',...
                                  'MarkerSize',10); axis(app.UIAxes4_3,[0 0.5 0 yMax]);
            h=bar(app.UIAxes4_3,app.specBRS.F(app.specBRS.areaLF),abs(app.specBRS.Pxy(app.specBRS.areaLF)));
            h.EdgeColor = 'r';
            h.FaceColor = 'r';
            
            h2=bar(app.UIAxes4_3,app.specBRS.F(app.specBRS.areaHF),abs(app.specBRS.Pxy(app.specBRS.areaHF)));
            h2.EdgeColor = 'b';
            h2.FaceColor = 'b';
            grid(app.UIAxes4_3,'on')
            xlabel(app.UIAxes4_3,'Fequency (Hz)')
            title(app.UIAxes4_3,'Cross Power Spectral Density - [SBP IBI]');
            legend(app.UIAxes4_3,'Cross-PSD','LF','HF')
            % ###########
            %subplot(3,2,4)
            hold(app.UIAxes4_4,'on');
            yMax = max(abs(app.specBRS.Cxy([app.specBRS.areaLF;app.specBRS.areaHF])));
            if isempty(yMax)
                 yMax = max(abs(app.specBRS.Cxy(fix(length(app.specBRS.Cxy)/5):length(app.specBRS.Cxy))));
            end
            plot(app.UIAxes4_4,app.specBRS.F,app.specBRS.Cxy(1:length(app.specBRS.F)),'c','LineWidth',2,...
                                  'MarkerEdgeColor','k',...
                                  'MarkerFaceColor','g',...
                                  'MarkerSize',10);axis(app.UIAxes4_4,[0 0.5 0 yMax]);
            h=bar(app.UIAxes4_4,app.specBRS.F(app.specBRS.areaLF),app.specBRS.Cxy(app.specBRS.areaLF));
            h.EdgeColor = 'r';
            h.FaceColor = 'r';
            
            h2=bar(app.UIAxes4_4,app.specBRS.F(app.specBRS.areaHF),app.specBRS.Cxy(app.specBRS.areaHF));
            h2.EdgeColor = 'b';
            h2.FaceColor = 'b';
            grid(app.UIAxes4_4,'on');
            xlabel(app.UIAxes4_4,'Fequency (Hz)')
            title(app.UIAxes4_4,'Coherence |K|^2');
            legend(app.UIAxes4_4,'Coherence', 'LF', 'HF')
            % ###########
            %subplot(3,2,5)
            hold(app.UIAxes4_5,'on');
            yMin = min(app.specBRS.AngleXY(app.specBRS.LFmin:app.specBRS.HFmax));
            yMax = max(app.specBRS.AngleXY(app.specBRS.LFmin:app.specBRS.HFmax));
            
            plot(app.UIAxes4_5,app.specBRS.F,app.specBRS.AngleXY(1:length(app.specBRS.F)),'c','LineWidth',2,...
                                  'MarkerEdgeColor','k',...
                                  'MarkerFaceColor','g',...
                                  'MarkerSize',10); axis(app.UIAxes4_5,[0 0.5 yMin yMax])
            h=bar(app.UIAxes4_5,app.specBRS.F(app.specBRS.areaLF),app.specBRS.AngleXY(app.specBRS.areaLF));
            h.EdgeColor = 'r';
            h.FaceColor = 'r';
            
            h2=bar(app.UIAxes4_5,app.specBRS.F(app.specBRS.areaHF),app.specBRS.AngleXY(app.specBRS.areaHF));
            h2.EdgeColor = 'b';
            h2.FaceColor = 'b';
            grid(app.UIAxes4_5,'on')
            xlabel(app.UIAxes4_5,'Fequency (Hz)')
            title(app.UIAxes4_5,'Angle T_{xy}');
            legend(app.UIAxes4_5,'Angle')
            % ###########
            %subplot(3,2,6)
            hold(app.UIAxes4_6,'on')
            yMaxT = max(abs(app.specBRS.Txy(app.specBRS.LFmin:app.specBRS.HFmax)));
            yMaxA = max(app.specBRS.AlphaXY(app.specBRS.LFmin:app.specBRS.HFmax));
            yMax = max(yMaxT,yMaxA);
            
            plot(app.UIAxes4_6,app.specBRS.F,abs(app.specBRS.Txy(1:length(app.specBRS.F))),'c','LineWidth',2,...
                                  'MarkerEdgeColor','k',...
                                  'MarkerFaceColor','g',...
                                  'MarkerSize',10); axis(app.UIAxes4_6, [0 0.5 0 yMax])
            plot(app.UIAxes4_6,app.specBRS.F,app.specBRS.AlphaXY(1:length(app.specBRS.F)),'black','LineWidth',2,...
                                  'MarkerEdgeColor','k',...
                                  'MarkerFaceColor','g',...
                                  'MarkerSize',10); axis(app.UIAxes4_6, [0 0.5 0 yMax])                 
            h=bar(app.UIAxes4_6,app.specBRS.F(app.specBRS.areaLF),abs(app.specBRS.Txy(app.specBRS.areaLF)));
            h.EdgeColor = 'r';
            h.FaceColor = 'r';
            
            h2=bar(app.UIAxes4_6,app.specBRS.F(app.specBRS.areaHF),abs(app.specBRS.Txy(app.specBRS.areaHF)));
            h2.EdgeColor = 'b';
            h2.FaceColor = 'b';
            grid(app.UIAxes4_6,'on')
            xlabel(app.UIAxes4_6,'Fequency (Hz)')
            title(app.UIAxes4_6,'BRS - Spectrum');
            legend(app.UIAxes4_6,'BRS-TranferFCT', 'BRS-AlphaIndex')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            isSomno = 0;
            isFP = 0;
            isTAP = 0;
          
            app.InterpolateArtefactsCheckBox.Value = 1;


        end

        % Menu selected function: SomnomedicsMenu
        function SomnomedicsMenuSelected(app, event)
            app.isSomno = 1;
            % RR File
            [app.STrrfile,app.STrrpath] = uigetfile('*.txt','Select RR-File!');
            if isequal(app.STrrfile,0)
                disp('You selected Cancel');
            else
                disp(['You selected Somnomedics RR - File: ', fullfile(app.STrrpath,app.STrrfile)]);
                
                % SBP File
                folder = [app.STrrpath,'\*.txt'];
                [app.STsbpfile,app.STsbppath] = uigetfile(folder,'Select SBP-File!');
                if isequal(app.STsbpfile,0)
                    disp('You selected Cancel');
                else
                    disp(['You selected Somnomedics RR - File: ', fullfile(app.STsbppath,app.STsbpfile)]);
                    readSomno(app);
                    disp('Remove Artefacts!');
                    p = app.ArtfctpEditField.Value;
                    app.RRuse = artefactFilter(app.RR,p);
                    app.SBPuse = artefactFilter(app.SBP,p);
                    disp('Plot Raw Data!');                 
                    plotValues(app);
                
                end
                
            end


        end

        % Value changed function: InterpolateArtefactsCheckBox
        function InterpolateArtefactsCheckBoxValueChanged(app, event)
            value = app.InterpolateArtefactsCheckBox.Value;
            if value
                p = app.ArtfctpEditField.Value;
                app.RRuse = artefactFilter(app.RR,p);
                app.SBPuse = artefactFilter(app.SBP,p);
                plotValues(app);
            else
                app.RRuse = app.RR;
                app.SBPuse = app.SBP;
                plotValues(app);
            end
        end

        % Value changed function: ArtfctpEditField
        function ArtfctpEditFieldValueChanged(app, event)
            value = app.ArtfctpEditField.Value;
            app.InterpolateArtefactsCheckBox.Value = 0;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            t = app.Time./1000;
            % Seq BRS
            app.seqBRS = brsSequence(app.RRuse,app.SBPuse,0,false);
            cla(app.UIAxes3);
            cla(app.UIAxes3_2);
            plotBRSseq(app);
            
            % xBRS
            app.xBRS =  brsX(app.RRuse,app.SBPuse,t,false);
            cla(app.UIAxes3_3);
            plotXbrs(app);
            
            % SpecBRS 
            app.aLF.Text = '-';app.aHF.Text = '-';app.tLF.Text = '-';app.tLF.Text = '-';drawnow
            app.specBRS = brsSpectral(app.RRuse,app.SBPuse,t,false);
            app.aLF.Text = sprintf('%.2f',app.specBRS.alphaLF);
            app.aHF.Text = sprintf('%.2f',app.specBRS.alphaHF);
            app.tLF.Text = sprintf('%.2f',app.specBRS.brsLF);
            app.tHF.Text = sprintf('%.2f',app.specBRS.brsHF);
            drawnow
            cla(app.UIAxes4);cla(app.UIAxes4_2);cla(app.UIAxes4_3);cla(app.UIAxes4_4);cla(app.UIAxes4_5);cla(app.UIAxes4_6);
            
            plotSpec(app);


        end

        % Menu selected function: FinapresMenu
        function FinapresMenuSelected(app, event)
            app.isFP = 1;
             [app.FPfile,app.FPpath] = uigetfile('*.txt','Select Finapres beats-file!');
            if isequal(app.STrrfile,0)
                disp('You selected Cancel');
            else
                disp(['You selectedBeatscope - File: ', fullfile(app.FPpath,app.FPfile)]);
                readFina(app);
                disp('Remove Artefacts!');
                p = app.ArtfctpEditField.Value;
                app.RRuse = artefactFilter(app.RR,p);
                app.SBPuse = artefactFilter(app.SBP,p);
                disp('Plot Raw Data!');                 
                plotValues(app);
                
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.ImportMenu = uimenu(app.DataMenu);
            app.ImportMenu.Text = 'Import';
            app.SomnomedicsMenu = uimenu(app.ImportMenu);
            app.SomnomedicsMenu.MenuSelectedFcn = createCallbackFcn(app, @SomnomedicsMenuSelected, true);
            app.SomnomedicsMenu.Text = 'Somnomedics';
            app.FinapresMenu = uimenu(app.ImportMenu);
            app.FinapresMenu.MenuSelectedFcn = createCallbackFcn(app, @FinapresMenuSelected, true);
            app.FinapresMenu.Text = 'Finapres';
            app.TableMenu = uimenu(app.ImportMenu);
            app.TableMenu.Enable = 'off';
            app.TableMenu.Text = 'Table';
            app.SaveMenu = uimenu(app.DataMenu);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.DataMenu);
            app.LoadMenu.Enable = 'off';
            app.LoadMenu.Text = 'Load';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Enable = 'off';
            app.EditMenu.Text = 'Edit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.robertmalinowskimedunigreifswalddeMenu = uimenu(app.HelpMenu);
            app.robertmalinowskimedunigreifswalddeMenu.Tooltip = {'Click to copy!'};
            app.robertmalinowskimedunigreifswalddeMenu.Text = 'robert.malinowski@med.uni-greifswald.de';
            app.BRSPanel = uipanel(app.UIFigure);
            app.BRSPanel.Title = 'BRS';
            app.TabGroup = uitabgroup(app.BRSPanel);
            app.SequenceBRSTab = uitab(app.TabGroup);
            app.SequenceBRSTab.Title = 'SequenceBRS';
            app.UIAxes3 = uiaxes(app.SequenceBRSTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, {''; ''})
            app.UIAxes3_2 = uiaxes(app.SequenceBRSTab);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, '')
            app.xBRSTab = uitab(app.TabGroup);
            app.xBRSTab.Title = 'xBRS';
            app.UIAxes3_3 = uiaxes(app.xBRSTab);
            title(app.UIAxes3_3, '')
            xlabel(app.UIAxes3_3, '')
            ylabel(app.UIAxes3_3, {''; ''})
            app.spectralBRSTab = uitab(app.TabGroup);
            app.spectralBRSTab.Title = 'spectralBRS';
            app.UIAxes4 = uiaxes(app.spectralBRSTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4_2 = uiaxes(app.spectralBRSTab);
            title(app.UIAxes4_2, '')
            xlabel(app.UIAxes4_2, '')
            ylabel(app.UIAxes4_2, '')
            app.UIAxes4_3 = uiaxes(app.spectralBRSTab);
            title(app.UIAxes4_3, '')
            xlabel(app.UIAxes4_3, '')
            ylabel(app.UIAxes4_3, '')
            app.UIAxes4_4 = uiaxes(app.spectralBRSTab);
            title(app.UIAxes4_4, '')
            xlabel(app.UIAxes4_4, '')
            ylabel(app.UIAxes4_4, '')
            app.UIAxes4_5 = uiaxes(app.spectralBRSTab);
            title(app.UIAxes4_5, '')
            xlabel(app.UIAxes4_5, '')
            ylabel(app.UIAxes4_5, '')
            app.UIAxes4_6 = uiaxes(app.spectralBRSTab);
            title(app.UIAxes4_6, '')
            xlabel(app.UIAxes4_6, '')
            ylabel(app.UIAxes4_6, '')
            app.alphaLFLabel = uilabel(app.spectralBRSTab);
            app.alphaLFLabel.Text = 'alphaLF';
            app.alphaHfLabel = uilabel(app.spectralBRSTab);
            app.alphaHfLabel.Text = 'alphaHf';
            app.transfLFLabel = uilabel(app.spectralBRSTab);
            app.transfLFLabel.Text = 'transfLF';
            app.transfHFLabel = uilabel(app.spectralBRSTab);
            app.transfHFLabel.Text = 'transfHF';
            app.aLF = uilabel(app.spectralBRSTab);
            app.aLF.Text = '-';
            app.aHF = uilabel(app.spectralBRSTab);
            app.aHF.Text = '-';
            app.tLF = uilabel(app.spectralBRSTab);
            app.tLF.Text = '-';
            app.tHF = uilabel(app.spectralBRSTab);
            app.tHF.Text = '-';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'RR')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'RR [ms]')
            app.UIAxes2 = uiaxes(app.PlotPanel);
            title(app.UIAxes2, 'SBP')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'SBP [mmHG]')
            app.PropertiesPanel = uipanel(app.UIFigure);
            app.PropertiesPanel.Title = 'Properties';
            app.RunButton = uibutton(app.PropertiesPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TabGroup2 = uitabgroup(app.PropertiesPanel);
            app.RawTab = uitab(app.TabGroup2);
            app.RawTab.Title = 'Raw';
            app.InterpolateArtefactsCheckBox = uicheckbox(app.RawTab);
            app.InterpolateArtefactsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpolateArtefactsCheckBoxValueChanged, true);
            app.InterpolateArtefactsCheckBox.Text = 'Interpolate Artefacts';
            app.ArtfctpEditFieldLabel = uilabel(app.RawTab);
            app.ArtfctpEditFieldLabel.Text = 'Artfct. p = ';
            app.ArtfctpEditField = uieditfield(app.RawTab, 'numeric');
            app.ArtfctpEditField.ValueChangedFcn = createCallbackFcn(app, @ArtfctpEditFieldValueChanged, true);
            app.ArtfctpEditField.Value = 0.4;
            app.SetinterpolationFactorLabel = uilabel(app.RawTab);
            app.SetinterpolationFactorLabel.Text = 'Set interpolation Factor:';
            app.SeqTab = uitab(app.TabGroup2);
            app.SeqTab.Title = 'Seq';
            app.lagEditFieldLabel = uilabel(app.SeqTab);
            app.lagEditFieldLabel.Text = 'lag =';
            app.lagEditField = uieditfield(app.SeqTab, 'numeric');
            app.lagEditField.Limits = [0 10];
            app.lagEditField.ValueDisplayFormat = '%.0f';
            app.SetlaginbeatsbetweenRRandSBPLabel = uilabel(app.SeqTab);
            app.SetlaginbeatsbetweenRRandSBPLabel.Text = 'Set lag (in beats) between RR and SBP:';
            app.SetminBPchangesinmmHgLabel = uilabel(app.SeqTab);
            app.SetminBPchangesinmmHgLabel.Text = 'Set min BP changes in mmHg:';
            app.deltaBPEditFieldLabel = uilabel(app.SeqTab);
            app.deltaBPEditFieldLabel.Text = 'deltaBP =';
            app.deltaBPEditField = uieditfield(app.SeqTab, 'numeric');
            app.deltaBPEditField.Limits = [0 10];
            app.deltaBPEditField.ValueDisplayFormat = '%.2f';
            app.deltaBPEditField.Value = 1;
            app.SetminBPchangesinmsLabel = uilabel(app.SeqTab);
            app.SetminBPchangesinmsLabel.Text = 'Set min BP changes in ms:';
            app.deltaRREditFieldLabel = uilabel(app.SeqTab);
            app.deltaRREditFieldLabel.Text = 'deltaRR =';
            app.deltaRREditField = uieditfield(app.SeqTab, 'numeric');
            app.deltaRREditField.Limits = [0 10];
            app.deltaRREditField.ValueDisplayFormat = '%.2f';
            app.deltaRREditField.Value = 6;
            app.SettheminnumberbeatsSequenceLabel = uilabel(app.SeqTab);
            app.SettheminnumberbeatsSequenceLabel.Text = 'Set the min number beats (Sequence):';
            app.minSeqLengthLabel = uilabel(app.SeqTab);
            app.minSeqLengthLabel.Text = 'minSeqLength =';
            app.minSeqLengthEditField = uieditfield(app.SeqTab, 'numeric');
            app.minSeqLengthEditField.Limits = [0 10];
            app.minSeqLengthEditField.ValueDisplayFormat = '%.0f';
            app.minSeqLengthEditField.Value = 3;
            app.XTab = uitab(app.TabGroup2);
            app.XTab.Title = 'X';
            app.SpecTab = uitab(app.TabGroup2);
            app.SpecTab.Title = 'Spec';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BRSoft_ver12

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end